classdef Project_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectTitle                   matlab.ui.control.Label
        FilteredImagesPanel            matlab.ui.container.Panel
        GridLayout1                    matlab.ui.container.GridLayout
        R1_Filtered_Image              matlab.ui.control.UIAxes
        R2_Filtered_Image              matlab.ui.control.UIAxes
        R3_Filtered_Image              matlab.ui.control.UIAxes
        ImageSelectionPanel            matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        SelectImageButton              matlab.ui.control.Button
        Original_Image                 matlab.ui.control.UIAxes
        FilterCharacteristicsPanel     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Group1FilterPanel              matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RemoveBGSpinner                matlab.ui.control.Spinner
        RemoveBGSpinnerLabel           matlab.ui.control.Label
        UpperCutoffLabel               matlab.ui.control.Label
        LowerCutoffLabel               matlab.ui.control.Label
        ApplyFIRButton                 matlab.ui.control.Button
        FIRFILTERDropDown              matlab.ui.control.DropDown
        BrightnessSliderLabel          matlab.ui.control.Label
        BrightnessSlider               matlab.ui.control.Slider
        LowerCutoffSlider              matlab.ui.control.Slider
        UpperCutoffSlider              matlab.ui.control.Slider
        FIRFILTERDropDownLabel         matlab.ui.control.Label
        Group2FilterPanel              matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        RGBRedButton                   matlab.ui.control.Button
        RGBGreenButton                 matlab.ui.control.Button
        RGBBlueButton                  matlab.ui.control.Button
        IntensityGrayscaleButton       matlab.ui.control.Button
        BinaryBilevelSlider            matlab.ui.control.Slider
        MorphologicalBackgroundEstimationSliderLabel  matlab.ui.control.Label
        MorphologicalBackgroundEstimationSlider  matlab.ui.control.Slider
        BinaryBilevelSliderLabel       matlab.ui.control.Label
        Group3FilterPanel              matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        IndexedPseudocolorButton       matlab.ui.control.Button
        MultiframeSpinner              matlab.ui.control.Spinner
        MultiframeSpinnerLabel         matlab.ui.control.Label
        ContrastAdjustmentSliderLabel  matlab.ui.control.Label
        ContrastAdjustmentSlider       matlab.ui.control.Slider
    end

    properties (Access = private)
        Selected_Image
        Image_1
        Image_2
        Image_3
        LCF
        UCF
        Edited_Image_1
        Edited_Image_2
        Edited_Image_3
    end
    
    methods (Access = private)
        
        %Selecting Image Sample
        function Image_Sample(app)
            [filename, pathname] = uigetfile('*.*', 'Pick a MATLAB code file');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User pressed cancel')
            else
                filename = strcat(pathname, filename);
                app.Selected_Image = imread(filename);
                imshow(app.Selected_Image, 'Parent', app.Original_Image);
                app.Image_1 = app.Selected_Image; 
                app.Image_2 = app.Selected_Image;
                app.Image_3 = app.Selected_Image;
            end
        end

        %Selecting FIR Filter Type
        function FIR_Type_Select(app, type)
            switch type
                case 'TYPES'
                    app.LowerCutoffSlider.Visible = 'off';
                    app.LowerCutoffLabel.Visible = 'off';
                    app.UpperCutoffSlider.Visible = 'off';
                    app.UpperCutoffLabel.Visible = 'off';
                    
                case 'LPF'
                    app.LowerCutoffSlider.Visible = 'off';
                    app.LowerCutoffLabel.Visible = 'off';
                    app.UpperCutoffSlider.Visible = 'on';
                    app.UpperCutoffLabel.Visible = 'on';
                    
                case 'HPF'
                    app.LowerCutoffSlider.Visible = 'on';
                    app.LowerCutoffLabel.Visible = 'on';
                    app.UpperCutoffSlider.Visible = 'off';
                    app.UpperCutoffLabel.Visible = 'off';
                    
                case 'BPF'
                    app.LowerCutoffSlider.Visible = 'on';
                    app.LowerCutoffLabel.Visible = 'on';
                    app.UpperCutoffSlider.Visible = 'on';
                    app.UpperCutoffLabel.Visible = 'on';
                    
                case 'BSF'
                    app.LowerCutoffSlider.Visible = 'on';
                    app.LowerCutoffLabel.Visible = 'on';
                    app.UpperCutoffSlider.Visible = 'on';
                    app.UpperCutoffLabel.Visible = 'on';
            end
        end
        
        %FIR Filtering based on the type
        function FIR(app, type, LFc, HFc)
            switch type
                case 'TYPES'
                    imshow(app.Image_1, 'Parent', app.R1_Filtered_Image);
                    
                case 'LPF'
                    if HFc ~= 0
                        edit = imgaussfilt(app.Selected_Image, 10.01 - (HFc / 10));
                        imshow(edit, 'Parent', app.R1_Filtered_Image);
                        app.Edited_Image_1 = edit;
                    else
                        imshow(app.Image_1, 'Parent', app.R1_Filtered_Image);
                    end
                    
                case 'HPF'
                    if LFc ~= 0
                        edit = imsharpen(app.Selected_Image,'Amount', LFc / 10);
                        imshow(edit, 'Parent', app.R1_Filtered_Image);
                        app.Edited_Image_1 = edit;
                    else
                        imshow(app.Image_1, 'Parent', app.R1_Filtered_Image);
                    end
                    
                case 'BPF'
                    edit = gaussianbpf(app, app.Selected_Image,LFc,HFc);
                    [R, C] = size(edit);
                    crop = imcrop(edit, [0, 0, R, C]); 
                    imshow(crop, 'Parent', app.R1_Filtered_Image);
                    app.Edited_Image_1 = crop;

                case 'BSF'
                    [edit, map] = rgb2ind(app.Selected_Image,2);
                    edit = medfilt2(edit, [round(LFc/20) round(HFc/20)]);
                    imshow(edit, map, 'Parent', app.R1_Filtered_Image);
                    app.Edited_Image_1 = edit;
            end
        end
        
        %FIR Filter Continuation for Bandpass filter
        function filtered_image = gaussianbpf(~, I,d0,d1)
            f = double(I);
            [nx ny] = size(f);
            f = uint8(f);
            fftI = fft2(f,2*nx-1,2*ny-1);
            fftI = fftshift(fftI);
            
            % Initialize filter.
            filter1 = ones(2*nx-1,2*ny-1);
            filter2 = ones(2*nx-1,2*ny-1);
            filter3 = ones(2*nx-1,2*ny-1);

            for i = 1:2*nx-1
                for j =1:2*ny-1
                    dist = ((i-(nx+1))^2 + (j-(ny+1))^2)^.5;
                    
                    % Use Gaussian filter.
                    filter1(i,j) = exp(-dist^2/(2*d1^2));
                    filter2(i,j) = exp(-dist^2/(2*d0^2));
                    filter3(i,j) = 1.0 - filter2(i,j);
                    filter3(i,j) = filter1(i,j).*filter3(i,j);
                end
            end
            
            % Update image with passed frequencies
            filtered_image = fftI + filter3.*fftI;
            filtered_image = ifftshift(filtered_image);
            filtered_image = ifft2(filtered_image,2*nx-1,2*ny-1);
            filtered_image = real(filtered_image(1:nx,1:ny));
            filtered_image = uint8(filtered_image);
        end

        %Remove or Subtract Background
        function BG_Remove(app, radius)
            background = imopen(app.Selected_Image, strel('disk', radius,  4));
            BG_Removed = imsubtract(app.Selected_Image, background);
            imshow(BG_Removed, 'Parent', app.R1_Filtered_Image);
            app.Edited_Image_1 = BG_Removed;
        end
        
        %Brightness Adjustment
        function Brightness(app, brightness)
            if brightness ~= 0
                edit = app.Image_1 + round(brightness);
                imagesc(app.R1_Filtered_Image, edit);
                axis(app.R1_Filtered_Image,'image');
                app.Edited_Image_1 = app.Image_1;
            else
                imagesc(app.R1_Filtered_Image, app.Image_1);
                axis(app.R1_Filtered_Image,'image');
            end
        end
        
        %RGB Image Type, Red Filter
        function RGB_Red(app)
            edit = app.Image_2;
            edit(:,:,2:3) = 0;
            imagesc(app.R2_Filtered_Image, edit);
            axis(app.R2_Filtered_Image,'image');
            app.Edited_Image_2 = edit;
        end
        
        %RGB Image Type, Green Filter
        function RGB_Green(app)
            edit = app.Image_2;
            edit(:,:,1) = 0;
            edit(:,:,3) = 0;
            imagesc(app.R2_Filtered_Image, edit);
            axis(app.R2_Filtered_Image,'image');
            app.Edited_Image_2 = edit;
        end
        
        %RGB Image Type, Blue Filter
        function RGB_Blue(app)
            edit = app.Image_2;
            edit(:,:,1:2) = 0;
            imagesc(app.R2_Filtered_Image, edit);
            axis(app.R2_Filtered_Image,'image');
            app.Edited_Image_2 = edit;
        end
        
        %Intensity/Grayscale Filter
        function Intensity(app)
            edit = im2gray(app.Image_2);
            imshow(edit, 'Parent',app.R2_Filtered_Image);
            axis(app.R2_Filtered_Image,'image');
            app.Edited_Image_2 = edit;
        end
        
        %Binary/Bilevel Filter
        function Binary(app, binary)
            if binary ~= 0
                edit = im2bw(app.Image_2, binary/100); %#ok<IM2BW> 
                imshow(edit, 'Parent', app.R2_Filtered_Image);
                axis(app.R2_Filtered_Image,'image');
                app.Edited_Image_2 = edit;
            else
                imagesc(app.R2_Filtered_Image, app.Image_2);
                axis(app.R2_Filtered_Image,'image');
            end
        end
        
        %Morphological Opening to Estimate Background
        function BG_Estimate(app, estimated)
            if estimated ~= 0
                edit = imopen(app.Image_2, strel('disk', round(estimated), 4));
                imagesc(app.R2_Filtered_Image, edit);
                axis(app.R2_Filtered_Image,'image');
                app.Edited_Image_2 = edit;
            else
                imagesc(app.R2_Filtered_Image, app.Image_2);
                axis(app.R2_Filtered_Image,'image');
            end
        end
        
        %Index/Pseudocolor Filter
        function Index(app)
            [ind, map] = rgb2ind(app.Image_3, 8);
            imagesc(app.R3_Filtered_Image, ind);
            axis(app.R3_Filtered_Image,'image');
            colormap(app.R3_Filtered_Image,map);
            app.Edited_Image_3 = app.Image_3;
        end
        
        % Multiframe Filter
        function Multiframe(app, multiframe)
            edit = repmat(app.Image_3, [multiframe multiframe]);
            imagesc(app.R3_Filtered_Image, edit);
            axis(app.R3_Filtered_Image,'image');
            app.Edited_Image_3;
        end
        
        % Contrast Filter
        function Contrast(app, contrast)
            if contrast ~= 0
                edit = localcontrast(app.Image_3, 0.3, contrast/100);
                imagesc(app.R3_Filtered_Image, edit);
                axis(app.R3_Filtered_Image,'image');
                app.Edited_Image_3 = app.Image_3;
            else
                imagesc(app.R3_Filtered_Image, app.Image_3);
                axis(app.R3_Filtered_Image,'image');
            end
        end
        
        %Reset Application
        function Reset(app)
            %Reset all filtered images
            app.Image_1 = app.Selected_Image;
            app.Image_2 = app.Selected_Image;
            app.Image_3 = app.Selected_Image;
            app.Edited_Image_1 = app.Selected_Image;
            app.Edited_Image_2 = app.Selected_Image;
            app.Edited_Image_3 = app.Selected_Image;
            
            %Reset filtered displays
            cla(app.R1_Filtered_Image);
            cla(app.R2_Filtered_Image);
            cla(app.R3_Filtered_Image);
            
            %Reset all user input data to the initial values
            %Group 1 Filter
            app.FIRFILTERDropDown.Value = 'TYPES';
            app.LCF = 0;
            app.LowerCutoffLabel.Visible = 'off';
            app.LowerCutoffSlider.Visible = 'off';
            app.LowerCutoffSlider.Value = 0;
            app.UCF = 0;
            app.UpperCutoffLabel.Visible = 'off';
            app.UpperCutoffSlider.Visible = 'off';
            app.UpperCutoffSlider.Value = 0;
            app.RemoveBGSpinner.Value = 1;
            app.BrightnessSlider.Value = 0;
            
            %Group 2 Filter
            app.BinaryBilevelSlider.Value = 0;
            app.MorphologicalBackgroundEstimationSlider.Value = 0;
            
            %Group 3 Filter
            app.MultiframeSpinner.Value = 1;
            app.ContrastAdjustmentSlider.Value = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            Image_Sample(app);
        end

        % Value changed function: FIRFILTERDropDown
        function FIRFILTERDropDownValueChanged(app, event)
            FIR_Type_Select(app, app.FIRFILTERDropDown.Value);
        end

        % Value changed function: LowerCutoffSlider
        function LowerCutoffSliderValueChanged(app, event)
            app.LCF = app.LowerCutoffSlider.Value;
        end

        % Value changed function: UpperCutoffSlider
        function UpperCutoffSliderValueChanged(app, event)
            app.UCF = app.UpperCutoffSlider.Value;
        end

        % Button pushed function: ApplyFIRButton
        function ApplyFIRButtonPushed(app, event)
            switch app.FIRFILTERDropDown.Value
                case 'TYPES'
                    FIR(app, app.FIRFILTERDropDown.Value, 0, 0);
                case 'LPF'
                    FIR(app, app.FIRFILTERDropDown.Value, 0, app.UCF);
                case 'HPF'
                    FIR(app, app.FIRFILTERDropDown.Value, app.LCF, 0);
                case 'BPF'
                    FIR(app, app.FIRFILTERDropDown.Value, app.LCF, app.UCF);
                case 'BSF'
                    FIR(app, app.FIRFILTERDropDown.Value, app.LCF, app.UCF);
            end
        end

        % Value changed function: RemoveBGSpinner
        function RemoveBGSpinnerValueChanged(app, event)
            BG_Remove(app, app.RemoveBGSpinner.Value);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            Brightness(app, app.BrightnessSlider.Value);
        end

        % Button pushed function: RGBRedButton
        function RGBRedButtonPushed(app, event)
            RGB_Red(app);
        end

        % Button pushed function: RGBGreenButton
        function RGBGreenButtonPushed(app, event)
            RGB_Green(app);
        end

        % Button pushed function: RGBBlueButton
        function RGBBlueButtonPushed(app, event)
            RGB_Blue(app);
        end

        % Button pushed function: IntensityGrayscaleButton
        function IntensityGrayscaleButtonPushed(app, event)
            Intensity(app);
        end

        % Value changed function: BinaryBilevelSlider
        function BinaryBilevelSliderValueChanged(app, event)
            Binary(app, app.BinaryBilevelSlider.Value);
        end

        % Value changed function: MorphologicalBackgroundEstimationSlider
        function MorphologicalBackgroundEstimationSliderValueChanged(app, event)
            BG_Estimate(app, app.MorphologicalBackgroundEstimationSlider.Value);
        end

        % Button pushed function: IndexedPseudocolorButton
        function IndexedPseudocolorButtonPushed(app, event)
            Index(app);
        end

        % Value changed function: MultiframeSpinner
        function MultiframeSpinnerValueChanged(app, event)
            Multiframe(app, app.MultiframeSpinner.Value);
        end

        % Value changed function: ContrastAdjustmentSlider
        function ContrastAdjustmentSliderValueChanged(app, event)
            Contrast(app, app.ContrastAdjustmentSlider.Value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            Reset(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilterCharacteristicsPanel = uipanel(app.UIFigure);
            app.FilterCharacteristicsPanel.Title = 'Filter Characteristics';
            app.GridLayout2 = uigridlayout(app.FilterCharacteristicsPanel);
            app.Group3FilterPanel = uipanel(app.GridLayout2);
            app.Group3FilterPanel.Title = 'Group 3 Filter';
            app.GridLayout5 = uigridlayout(app.Group3FilterPanel);
            app.ContrastAdjustmentSlider = uislider(app.GridLayout5);
            app.ContrastAdjustmentSlider.Limits = [-100 100];
            app.ContrastAdjustmentSlider.MajorTicks = [-100 -50 0 50 100];
            app.ContrastAdjustmentSlider.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.ContrastAdjustmentSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastAdjustmentSliderValueChanged, true);
            app.ContrastAdjustmentSlider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.ContrastAdjustmentSliderLabel = uilabel(app.GridLayout5);
            app.ContrastAdjustmentSliderLabel.Text = 'Contrast Adjustment';
            app.MultiframeSpinnerLabel = uilabel(app.GridLayout5);
            app.MultiframeSpinnerLabel.Text = 'Multiframe';
            app.MultiframeSpinner = uispinner(app.GridLayout5);
            app.MultiframeSpinner.ValueChangedFcn = createCallbackFcn(app, @MultiframeSpinnerValueChanged, true);
            app.MultiframeSpinner.Value = 1;
            app.IndexedPseudocolorButton = uibutton(app.GridLayout5, 'push');
            app.IndexedPseudocolorButton.ButtonPushedFcn = createCallbackFcn(app, @IndexedPseudocolorButtonPushed, true);
            app.IndexedPseudocolorButton.Text = 'Indexed/Pseudocolor';
            app.Group2FilterPanel = uipanel(app.GridLayout2);
            app.Group2FilterPanel.Title = 'Group 2 Filter';
            app.GridLayout4 = uigridlayout(app.Group2FilterPanel);
            app.BinaryBilevelSliderLabel = uilabel(app.GridLayout4);
            app.BinaryBilevelSliderLabel.Text = 'Binary/Bilevel';
            app.MorphologicalBackgroundEstimationSlider = uislider(app.GridLayout4);
            app.MorphologicalBackgroundEstimationSlider.ValueChangedFcn = createCallbackFcn(app, @MorphologicalBackgroundEstimationSliderValueChanged, true);
            app.MorphologicalBackgroundEstimationSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 96 100];
            app.MorphologicalBackgroundEstimationSliderLabel = uilabel(app.GridLayout4);
            app.MorphologicalBackgroundEstimationSliderLabel.Text = 'Morphological Background Estimation';
            app.BinaryBilevelSlider = uislider(app.GridLayout4);
            app.BinaryBilevelSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryBilevelSliderValueChanged, true);
            app.BinaryBilevelSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 96 100];
            app.IntensityGrayscaleButton = uibutton(app.GridLayout4, 'push');
            app.IntensityGrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityGrayscaleButtonPushed, true);
            app.IntensityGrayscaleButton.Text = 'Intensity/Grayscale';
            app.RGBBlueButton = uibutton(app.GridLayout4, 'push');
            app.RGBBlueButton.ButtonPushedFcn = createCallbackFcn(app, @RGBBlueButtonPushed, true);
            app.RGBBlueButton.Text = 'RGB/Truecolor(Blue)';
            app.RGBGreenButton = uibutton(app.GridLayout4, 'push');
            app.RGBGreenButton.ButtonPushedFcn = createCallbackFcn(app, @RGBGreenButtonPushed, true);
            app.RGBGreenButton.Text = 'RGB/Truecolor(Green)';
            app.RGBRedButton = uibutton(app.GridLayout4, 'push');
            app.RGBRedButton.ButtonPushedFcn = createCallbackFcn(app, @RGBRedButtonPushed, true);
            app.RGBRedButton.Text = 'RGB/Truecolor(Red)';
            app.Group1FilterPanel = uipanel(app.GridLayout2);
            app.Group1FilterPanel.Title = 'Group 1 Filter';
            app.GridLayout3 = uigridlayout(app.Group1FilterPanel);
            app.FIRFILTERDropDownLabel = uilabel(app.GridLayout3);
            app.FIRFILTERDropDownLabel.Text = 'FIR FILTER';
            app.UpperCutoffSlider = uislider(app.GridLayout3);
            app.UpperCutoffSlider.ValueChangedFcn = createCallbackFcn(app, @UpperCutoffSliderValueChanged, true);
            app.UpperCutoffSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 96 100];
            app.UpperCutoffSlider.Visible = 'off';
            app.LowerCutoffSlider = uislider(app.GridLayout3);
            app.LowerCutoffSlider.ValueChangedFcn = createCallbackFcn(app, @LowerCutoffSliderValueChanged, true);
            app.LowerCutoffSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 96 100];
            app.LowerCutoffSlider.Visible = 'off';
            app.BrightnessSlider = uislider(app.GridLayout3);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.MajorTicks = [-100 -50 0 50 100];
            app.BrightnessSlider.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.BrightnessSliderLabel = uilabel(app.GridLayout3);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.FIRFILTERDropDown = uidropdown(app.GridLayout3);
            app.FIRFILTERDropDown.Items = {'TYPES', 'LPF', 'HPF', 'BPF', 'BSF'};
            app.FIRFILTERDropDown.ValueChangedFcn = createCallbackFcn(app, @FIRFILTERDropDownValueChanged, true);
            app.FIRFILTERDropDown.Value = 'TYPES';
            app.ApplyFIRButton = uibutton(app.GridLayout3, 'push');
            app.ApplyFIRButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFIRButtonPushed, true);
            app.ApplyFIRButton.Text = 'Apply FIR';
            app.LowerCutoffLabel = uilabel(app.GridLayout3);
            app.LowerCutoffLabel.Visible = 'off';
            app.LowerCutoffLabel.Text = 'Lower Cut-off';
            app.UpperCutoffLabel = uilabel(app.GridLayout3);
            app.UpperCutoffLabel.Visible = 'off';
            app.UpperCutoffLabel.Text = 'Upper Cut-off';
            app.RemoveBGSpinnerLabel = uilabel(app.GridLayout3);
            app.RemoveBGSpinnerLabel.Text = 'Remove BG';
            app.RemoveBGSpinner = uispinner(app.GridLayout3);
            app.RemoveBGSpinner.ValueChangedFcn = createCallbackFcn(app, @RemoveBGSpinnerValueChanged, true);
            app.RemoveBGSpinner.Value = 1;
            app.ImageSelectionPanel = uipanel(app.UIFigure);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.Original_Image = uiaxes(app.ImageSelectionPanel);
            title(app.Original_Image, 'Original Image Sample ')
            app.Original_Image.XTick = [];
            app.Original_Image.YTick = [];
            app.SelectImageButton = uibutton(app.ImageSelectionPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ResetButton = uibutton(app.ImageSelectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FilteredImagesPanel = uipanel(app.UIFigure);
            app.FilteredImagesPanel.Title = 'Filtered Images';
            app.GridLayout1 = uigridlayout(app.FilteredImagesPanel);
            app.R3_Filtered_Image = uiaxes(app.GridLayout1);
            title(app.R3_Filtered_Image, 'Group/Row 3 Filtered Image')
            app.R3_Filtered_Image.XTick = [];
            app.R3_Filtered_Image.YTick = [];
            app.R2_Filtered_Image = uiaxes(app.GridLayout1);
            title(app.R2_Filtered_Image, 'Group/Row 2 Filtered Image')
            app.R2_Filtered_Image.XTick = [];
            app.R2_Filtered_Image.YTick = [];
            app.R1_Filtered_Image = uiaxes(app.GridLayout1);
            title(app.R1_Filtered_Image, 'Group/Row 1 Filtered Image')
            app.R1_Filtered_Image.XTick = [];
            app.R1_Filtered_Image.YTick = [];
            app.ProjectTitle = uilabel(app.UIFigure);
            app.ProjectTitle.Text = 'MATLAB Image Processing Project with GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end